classdef StarPostdiff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RTKUIFigure                matlab.ui.Figure
        Toolbar                    matlab.ui.container.Toolbar
        PushTool2                  matlab.ui.container.toolbar.PushTool
        Panel                      matlab.ui.container.Panel
        RovButtond_2               matlab.ui.control.Button
        TureCoorEditField          matlab.ui.control.EditField
        Tureoutpath                matlab.ui.control.Button
        Switch                     matlab.ui.control.Switch
        TureCoorXYZEditField       matlab.ui.control.EditField
        TureCoorXYZEditFieldLabel  matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        TureCoorDateDatePicker     matlab.ui.control.DatePicker
        Label_7                    matlab.ui.control.Label
        plotRovCoorButton          matlab.ui.control.Button
        plotTureCoorButton         matlab.ui.control.Button
        Label_5                    matlab.ui.control.Label
        TureButton_start           matlab.ui.control.Button
        TureCoorZEditField         matlab.ui.control.EditField
        TureCoorZLabel             matlab.ui.control.Label
        TureCoorYEditField         matlab.ui.control.EditField
        TureCoorYLabel             matlab.ui.control.Label
        TureCoorXEditField         matlab.ui.control.EditField
        CoorXEditField_2Label_2    matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        CoorXYZEditField           matlab.ui.control.EditField
        CoorXYZEditFieldLabel      matlab.ui.control.Label
        refCoorDropDown            matlab.ui.control.DropDown
        CMDButton_start            matlab.ui.control.Button
        CMDConfEditField           matlab.ui.control.EditField
        Label_6                    matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        exeCheckBox                matlab.ui.control.CheckBox
        CoorZEditField             matlab.ui.control.EditField
        CoorZLabel                 matlab.ui.control.Label
        CoorYEditField             matlab.ui.control.EditField
        CoorXLabel                 matlab.ui.control.Label
        CoorXEditField             matlab.ui.control.EditField
        CoorXEditField_2Label      matlab.ui.control.Label
        Button_start               matlab.ui.control.Button
        ConfEditField              matlab.ui.control.EditField
        Label_4                    matlab.ui.control.Label
        ConfButtond                matlab.ui.control.Button
        RovObsEditField            matlab.ui.control.EditField
        Label_3                    matlab.ui.control.Label
        RovButtond                 matlab.ui.control.Button
        RovobsDropDown             matlab.ui.control.DropDown
        RefObsEditField            matlab.ui.control.EditField
        Label_2                    matlab.ui.control.Label
        RefButtond                 matlab.ui.control.Button
        RefobsDropDown             matlab.ui.control.DropDown
        NavButtond                 matlab.ui.control.Button
        NavDropDown                matlab.ui.control.DropDown
        NavEditField               matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             addpath(genpath(pwd));
%             warning('off');
%             app.RTKUIFigure.WindowStyle="normal";
%             icopath=[pwd,'\1.icon\star.png'];
%             app.RTKUIFigure.Icon=icopath;
%             app.RTKUIFigure.Position=[650 450 687 341];
%             app.RTKUIFigure.Name='Post Diff';
%             app.RTKUIFigure.Visible="on";
      warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved');%禁用警告的方法
        end

        % Button pushed function: NavButtond
        function NavButtondPushed(app, event)
           [file,path] = uigetfile({'*.bin;*.**p;*.rtcm;*.RTCM;*.rnx';},'Select the file');
           if ischar(file)
               navfile = fullfile(path,file);
%              app.Observation.Editable = 'on';
               app.NavEditField.Value    = navfile;
           end
        end

        % Value changed function: NavEditField
        function NavEditFieldValueChanged(app, event)
            value = app.NavEditField.Value;
            
        end

        % Button pushed function: RefButtond
        function RefButtondPushed(app, event)
            if isempty(app.RovObsEditField.Value)
                [reffile,refpath] = uigetfile({'*.bin;*.**o;*.rtcm;*.RTCM'},'Select the file');
                if refpath~=0
                app.RovObsEditField.Value=refpath;
                app.TureCoorEditField.Value=refpath;
                end
            else
                [reffile,refpath] = uigetfile({'*.bin;*.**o;*.rtcm;*.RTCM'},'Select the file',app.RovObsEditField.Value);
            end
           if ischar(reffile)
               if refpath~=0
               Robsfile = fullfile(refpath,reffile);
%              app.Observation.Editable = 'on';
               app.RefObsEditField.Value    = Robsfile;
               end
           end
           app.Lamp.Enable="off";
        end

        % Value changed function: RefObsEditField
        function RefObsEditFieldValueChanged(app, event)
            value = app.RefObsEditField.Value;
            
        end

        % Value changed function: RovobsDropDown
        function RovobsDropDownValueChanged(app, event)
            value = app.RovobsDropDown.Value;
            
        end

        % Button pushed function: RovButtond
        function RovButtondPushed(app, event)
            if isempty(app.RefObsEditField.Value)
               [rovfile,rovpath] = uigetfile({'*.bin;*.**o;*.rtcm;*.RTCM'},'Select the file');
               if rovpath~=0
               app.TureCoorEditField.Value=rovpath;
               app.RefObsEditField.Value=rovpath;
               end
            else

               [rovfile,rovpath] = uigetfile({'*.bin;*.**o;*.rtcm;*.RTCM'},'Select the file',app.RefObsEditField.Value);
               if rovpath~=0
               app.TureCoorEditField.Value=rovpath;
               end
            end
           if ischar(rovfile)
               Rovobsfile = fullfile(rovpath,rovfile);
%              app.Observation.Editable = 'on';
               app.RovObsEditField.Value    = Rovobsfile;
           end
           app.Lamp.Enable="off";
           app.plotTureCoorButton.Enable="on";
           app.plotRovCoorButton.Enable="on";
        end

        % Value changed function: RovObsEditField
        function RovObsEditFieldValueChanged(app, event)
            value = app.RovObsEditField.Value;
            
        end

        % Button pushed function: ConfButtond
        function ConfButtondPushed(app, event)
           [conffile,confpath] = uigetfile({'*.conf'},'Select the file');
            if ischar(conffile)
               confffile = fullfile(confpath,conffile);
%              app.Observation.Editable = 'on';
               app.ConfEditField.Value    = confffile;
            end  
            app.Lamp.Enable="off";
        end

        % Value changed function: ConfEditField
        function ConfEditFieldValueChanged(app, event)
            value = app.ConfEditField.Value;
            
        end

        % Value changed function: RefobsDropDown
        function RefobsDropDownValueChanged(app, event)
            Obsvalue = app.RefobsDropDown.Value;
            
        end

        % Value changed function: NavDropDown
        function NavDropDownValueChanged(app, event)
            value = app.NavDropDown.Value;
            
        end

        % Button pushed function: Button_start
        function Button_startPushed(app, event)
            Navtype=app.NavDropDown.Value;
            Rovtype=app.RovobsDropDown.Value;
            Reftype=app.RefobsDropDown.Value;
            if strcmp(app.refCoorDropDown.Value,"Input TureCoor")
                coor_mode='0';
            elseif strcmp(app.refCoorDropDown.Value,"File Header")
                coor_mode='3';
            elseif strcmp(app.refCoorDropDown.Value,"Average Single")
                coor_mode='1';
            elseif strcmp(app.refCoorDropDown.Value,"Pos file")
                coor_mode='2';
            end
            if strcmp(Navtype,'RTCM')
                Navtype='1';
            else
                Navtype='0';
            end
            if strcmp(Rovtype,'RTCM')
                Rovtype='1';
            else
                Rovtype='0';
            end
            if strcmp(Reftype,'RTCM')
                Reftype='1';
            else
                Reftype='0';
            end
            exepath=[pwd,'\2.exe\Post_Diff.exe'];
            i=strfind(app.RovObsEditField.Value,'\');
            % 去除工程文件名，得到路径
            configpath=app.RovObsEditField.Value(1:i(end));
%             icopath=[icopath,'icon\'];
            % 加入logo图标文件名
            configpath = [configpath,'configerexe.txt'];
            
            fid=fopen(configpath,'w+');
            fprintf(fid,'%s %s %s %s %s %s %s %s',exepath,app.NavEditField.Value,app.RovObsEditField.Value,app.RefObsEditField.Value,Navtype,Rovtype,app.ConfEditField.Value,coor_mode);
            configerpath=[exepath,' ',app.NavEditField.Value,' ',app.RovObsEditField.Value,' ',app.RefObsEditField.Value,' ',Navtype,' ',Rovtype,' ',app.ConfEditField.Value,' ',coor_mode];
            if strcmp(app.refCoorDropDown.Value,"Input TureCoor")
                if ~isempty(app.CoorXYZEditField.Value)%XYZ转X Y Z坐标转换
                tmp = regexp(app.CoorXYZEditField.Value,'([^ ,:]*)','tokens');
                out = cat(2,tmp{:});
                app.CoorXEditField.Value=out{1};
                app.CoorYEditField.Value=out{2};
                app.CoorZEditField.Value=out{3};
                end
                Coor=[app.CoorXEditField.Value,',',app.CoorYEditField.Value,',',app.CoorZEditField.Value];
                fprintf(fid,' %s',Coor);
                configerpath=[configerpath,' ',Coor];
            end
            fclose(fid);
            app.CMDConfEditField.Value=configerpath;
            msgbox('写入configerexe文件成功','提示');
            if app.exeCheckBox.Value==1
                app.Lamp.Enable="on";
                app.Lamp.Color='green';
                pause(1)
                h1=msgbox('正在解算请稍等.........','提示');
                jframe = get(handle(h1),'javaframe');
                exepath=[pwd,'\1.icon\提示.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
               [status, result]=system(configerpath,'-echo');
               h1=msgbox('解算完成，请查看','提示');
                jframe = get(handle(h1),'javaframe');
                exepath=[pwd,'\1.icon\提示.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
               app.Lamp.Color='red';
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,"On")
                app.CoorZEditField.Enable = 'on';
                app.CoorXEditField.Enable = 'on';
                app.CoorYEditField.Enable = 'on';
            else
                app.CoorZEditField.Enable = 'off';
                app.CoorXEditField.Enable = 'off';
                app.CoorYEditField.Enable = 'off';
            end

        end

        % Value changed function: CoorXEditField
        function CoorXEditFieldValueChanged(app, event)
            value = app.CoorXEditField.Value;
            
        end

        % Value changed function: exeCheckBox
        function exeCheckBoxValueChanged(app, event)
            value = app.exeCheckBox.Value;
            
        end

        % Value changed function: TureCoorZEditField
        function TureCoorZEditFieldValueChanged(app, event)
            value = app.TureCoorZEditField.Value;
            
        end

        % Button pushed function: TureButton_start
        function TureButton_startPushed(app, event)
            %x
            X=extractBefore(app.TureCoorXEditField.Value,'.');
            XYZ=extractBefore(app.TureCoorXYZEditField.Value,'.');
            if isempty(X)&&isempty(XYZ)
                 h=errordlg('请输入正确TureCoor！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon);
               return
            end
            if app.Switch.Value=="off"
                tmp = regexp(app.TureCoorXYZEditField.Value,'([^ ,:]*)','tokens');
                out = cat(2,tmp{:});
                app.TureCoorXEditField.Value=out{1};
                app.TureCoorYEditField.Value=out{2};
                app.TureCoorZEditField.Value=out{3};
            end
            X=extractBefore(app.TureCoorXEditField.Value,'.');
            sizeX=size(app.TureCoorXEditField.Value,2)-size(X,2)-1;
            Xbase=sizeX-3;
            app.TureCoorXEditField.Value=app.TureCoorXEditField.Value(1:size(app.TureCoorXEditField.Value,2)-Xbase);
            %Y
            Y=extractBefore(app.TureCoorYEditField.Value,'.');
            if isempty(Y)
                 h=errordlg('请输入正确TureCoor！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon);
               return
            end
            sizeY=size(app.TureCoorYEditField.Value,2)-size(Y,2)-1;
            Ybase=sizeY-3;
            app.TureCoorYEditField.Value=app.TureCoorYEditField.Value(1:size(app.TureCoorYEditField.Value,2)-Ybase);
            %Z
            Z=extractBefore(app.TureCoorZEditField.Value,'.');
            if isempty(Z)
                 h=errordlg('请输入正确TureCoor！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
               return
            end
            sizeZ=size(app.TureCoorZEditField.Value,2)-size(Z,2)-1;
            Zbase=sizeZ-3;
            app.TureCoorZEditField.Value=app.TureCoorZEditField.Value(1:size(app.TureCoorZEditField.Value,2)-Zbase);

            if isempty(app.TureCoorEditField.Value)
                h=errordlg('请设置真值坐标输出路径，以便识别到真值坐标文件储存的目标路径！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
                return
            end
%             i=strfind(app.RovObsEditField.Value,'\');
%             ture_path=app.RovObsEditField.Value(1:i(end));
%             ture_path = [ture_path,'\TureCoor.txt'];
            ture_path = [app.TureCoorEditField.Value,'\TureCoor.txt'];
            fidTure=fopen(ture_path,'w+');
            if fidTure<0
                 h=errordlg('打开真值坐标TureCoor.txt失败，请检查输出路径是否正确！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
            end
            fprintf(fidTure,'%s\n','%  GPST                      x-ecef(m)      y-ecef(m)      z-ecef(m)   Q  ns   sdx(m)   sdy(m)   sdz(m)  sdxy(m)  sdyz(m)  sdzx(m) age(s)  ratio');
            fprintf(fidTure,'%s%s%s\n',[[datestr(app.TureCoorDateDatePicker.Value,26),' ',app.EditField.Value],'  ',app.TureCoorXEditField.Value,'    ',app.TureCoorYEditField.Value,'    ',app.TureCoorZEditField.Value,'    5  12   0.0066   0.0119   0.0091  -0.0067   0.0072  -0.0056   0.00   85.1']);
            fprintf(fidTure,'\n');
            fclose(fidTure);
            pause(1);
             h2=msgbox('真值坐标格式成功转换，请流动站路径下查看','提示');
                jframe = get(handle(h2),'javaframe');
                exepath=[pwd,'\1.icon\成功.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
             app.plotTureCoorButton.Enable="on";
             app.Label_5.Enable="on";
        end

        % Button pushed function: plotTureCoorButton
        function plotTureCoorButtonPushed(app, event)
            
            plotexepath=[pwd,'\2.exe\rtkplot.exe'];
            if isempty(app.TureCoorEditField.Value)
                h=errordlg('请设置真值坐标输出文件路径，以便识别到真值坐标文件储存的目标路径！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
                app.plotTureCoorButton.Enable="off";
                return
            end
%             i=strfind(app.RovObsEditField.Value,'\');
%             ture_path=app.RovObsEditField.Value(1:i(end));
%             ture_path = [ture_path,'TureCoor.txt'];
            ture_path = [app.TureCoorEditField.Value,'\TureCoor.txt'];
            path=[plotexepath,' ',ture_path];
            if exist(ture_path)<=0
                h=errordlg('文件中没有TureCoor.txt，请先进行真坐标格式转换！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
                app.plotTureCoorButton.Enable="off";
                return
            end
            [status, result]=dos(path);
        end

        % Button pushed function: plotRovCoorButton
        function plotRovCoorButtonPushed(app, event)
            plotexepath=[pwd,'\2.exe\rtkplot.exe'];
            if isempty(app.RovObsEditField.Value)
                h=errordlg('请设置目标rov文件路径，以便识别到rov结果文件！');
                jframe = get(handle(h),'javaframe');
                exepath=[pwd,'\1.icon\错误.png'];
                icon = javax.swing.ImageIcon(exepath);
                jframe.setFigureIcon(icon); 
                app.plotRovCoorButton.Enable="off";
                return
            end
            rov_path=[app.RovObsEditField.Value,'-.v3.0_mulfile'];
            path=[plotexepath,' ',rov_path];
            [status, result]=dos(path);
        end

        % Button pushed function: CMDButton_start
        function CMDButton_startPushed(app, event)
            !dir&
        end

        % Value changed function: refCoorDropDown
        function refCoorDropDownValueChanged(app, event)
            value = app.refCoorDropDown.Value;
            if strcmp(value,"Input TureCoor")
                app.CoorZEditField.Enable = 'on';
                app.CoorXEditField.Enable = 'on';
                app.CoorYEditField.Enable = 'on';
                app.CoorXYZEditField.Enable="on";
            else
                app.CoorZEditField.Enable = 'off';
                app.CoorXEditField.Enable = 'off';
                app.CoorYEditField.Enable = 'off';
                app.CoorXYZEditField.Enable="off";
            end

        end

        % Callback function
        function SystemLogSizeChanged(app, event)

        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            
        end

        % Value changed function: TureCoorDateDatePicker
        function TureCoorDateDatePickerValueChanged(app, event)
            value = app.TureCoorDateDatePicker.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changing function: RefObsEditField
        function RefObsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: CoorXYZEditField
        function CoorXYZEditFieldValueChanged(app, event)
            value = app.CoorXYZEditField.Value;
            
        end

        % Value changed function: TureCoorEditField
        function TureCoorEditFieldValueChanged(app, event)
            value = app.TureCoorEditField.Value;
            
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value;
            if value=="off"
                app.TureCoorXEditField.Enable="off";
                app.TureCoorYEditField.Enable="off";
                app.TureCoorZEditField.Enable="off";
                app.TureCoorXYZEditField.Enable="on";
            else
                app.TureCoorXEditField.Enable="on";
                app.TureCoorYEditField.Enable="on";
                app.TureCoorZEditField.Enable="on";
                app.TureCoorXYZEditField.Enable="off";
            end
        end

        % Value changed function: TureCoorXEditField
        function TureCoorXEditFieldValueChanged(app, event)
            value = app.TureCoorXEditField.Value;
            
        end

        % Value changed function: TureCoorXYZEditField
        function TureCoorXYZEditFieldValueChanged(app, event)
            value = app.TureCoorXYZEditField.Value;
            
        end

        % Button pushed function: Tureoutpath
        function TureoutpathButtonPushed(app, event)
         filepath=uigetdir('*.*','请选择真值坐标输出⽂件夹');
         if ischar(filepath)
            app.TureCoorEditField.Value=filepath;
            app.plotTureCoorButton.Enable="on";
         end
        end

        % Button pushed function: RovButtond_2
        function RovButtond_2Pushed(app, event)
             if ~isempty(app.RovObsEditField.Value)
                 fid=fopen([app.RovObsEditField.Value,'-.v3.0_mulfile'],'r');
                 if fid<0
                    [shibiefile,shibiepath] = uigetfile({'*.*'},'请选择自动识别的pos文件',app.RovObsEditField.Value);
                    if ischar(shibiefile)
                    fid=fopen([shibiepath,shibiefile],'r');
                    while not(feof(fid))
                        line = fgetl(fid);
                        if contains(line,'% obs start :')%支持exploerpos识别
                            yearstart=line(15:18);
                            monstart=line(20:21);
                            daystart=line(23:24);
                            hourstart=line(26:27);
                            minstart=line(29:30);
                            secstart=line(32:35);
                        end
                        if contains(line,'%   t_start:')%支持netdiff pos识别
                            yearstart=line(20:23);
                            monstart=line(25:26);
                            daystart=line(28:29);
                            hourstart=line(31:32);
                            minstart=line(34:35);
                            secstart=line(37:40);
                        end                            
                        if contains(line,'% obs end   :')%支持exploerpos识别
                            yearend=line(15:18);
                            monend=line(20:21);
                            dayend=line(23:24);
                            hourend=line(26:27);
                            minend=line(29:30);
                            secend=line(32:35);
                            break;
                        end
                        if contains(line,'%    t_end:')%支持netdiff识别
                            yearend=line(20:23);
                            monend=line(25:26);
                            dayend=line(28:29);
                            hourend=line(31:32);
                            minend=line(34:35);
                            secend=line(37:40);
                            break
                        end
                    end
                    t1 = datetime(str2num(yearstart),str2num(monstart),str2num(daystart),str2num(hourstart),str2num(minstart),str2num(secstart));
                    t2=datetime(str2num(yearend),str2num(monend),str2num(dayend),str2num(hourend),str2num(minend),str2num(secend));
                    t_middle = t1 + (t2-t1)/2;
                    app.TureCoorDateDatePicker.Value=t_middle;
                    app.EditField.Value=[datestr(t_middle,13),'.000'];
                    fclose(fid);
                   h2=msgbox('真值时间中点识别成功，如想修改时间请自行修改','提示');
                   jframe = get(handle(h2),'javaframe');
                   exepath=[pwd,'\1.icon\成功.png'];
                   icon = javax.swing.ImageIcon(exepath);
                   jframe.setFigureIcon(icon); 
                    return;
                 else
                    while not(feof(fid))
                        line = fgetl(fid);
                        if contains(line,'% obs start :')%支持exploerpos识别
                            yearstart=line(15:18);
                            monstart=line(20:21);
                            daystart=line(23:24);
                            hourstart=line(26:27);
                            minstart=line(29:30);
                            secstart=line(32:35);
                        end
                        if contains(line,'%   t_start:')%支持netdiff pos识别
                            yearstart=line(20:23);
                            monstart=line(25:26);
                            daystart=line(28:29);
                            hourstart=line(31:32);
                            minstart=line(34:35);
                            secstart=line(37:40);
                        end                            
                        if contains(line,'% obs end   :')%支持exploerpos识别
                            yearend=line(15:18);
                            monend=line(20:21);
                            dayend=line(23:24);
                            hourend=line(26:27);
                            minend=line(29:30);
                            secend=line(32:35);
                            break;
                        end
                        if contains(line,'%    t_end:')%支持netdiff识别
                            yearend=line(20:23);
                            monend=line(25:26);
                            dayend=line(28:29);
                            hourend=line(31:32);
                            minend=line(34:35);
                            secend=line(37:40);
                            break
                        end
                    end
                    t1 = datetime(str2num(yearstart),str2num(monstart),str2num(daystart),str2num(hourstart),str2num(minstart),str2num(secstart));
                    t2=datetime(str2num(yearend),str2num(monend),str2num(dayend),str2num(hourend),str2num(minend),str2num(secend));
                    t_middle = t1 + (t2-t1)/2;
                    app.TureCoorDateDatePicker.Value=t_middle;
                    app.EditField.Value=[datestr(t_middle,13),'.000'];
                    fclose(fid);
                    h2=msgbox('真值时间中点识别成功，如想修改时间请自行修改','提示');
                   jframe = get(handle(h2),'javaframe');
                   exepath=[pwd,'\1.icon\成功.png'];
                   icon = javax.swing.ImageIcon(exepath);
                   jframe.setFigureIcon(icon); 
                    return;
                    end
                 end
             end
              [shibiefile,shibiepath] = uigetfile({'*.*'},'请选择自动识别的pos文件');
              fid=fopen([shibiepath,shibiefile],'r');
                    while not(feof(fid))
                        line = fgetl(fid);
                        if contains(line,'% obs start :')%支持exploerpos识别
                            yearstart=line(15:18);
                            monstart=line(20:21);
                            daystart=line(23:24);
                            hourstart=line(26:27);
                            minstart=line(29:30);
                            secstart=line(32:35);
                        end
                        if contains(line,'%   t_start:')%支持netdiff pos识别
                            yearstart=line(20:23);
                            monstart=line(25:26);
                            daystart=line(28:29);
                            hourstart=line(31:32);
                            minstart=line(34:35);
                            secstart=line(37:40);
                        end                            
                        if contains(line,'% obs end   :')%支持exploerpos识别
                            yearend=line(15:18);
                            monend=line(20:21);
                            dayend=line(23:24);
                            hourend=line(26:27);
                            minend=line(29:30);
                            secend=line(32:35);
                            break;
                        end
                        if contains(line,'%    t_end:')%支持netdiff识别
                            yearend=line(20:23);
                            monend=line(25:26);
                            dayend=line(28:29);
                            hourend=line(31:32);
                            minend=line(34:35);
                            secend=line(37:40);
                            break
                        end
                    end
                    t1 = datetime(str2num(yearstart),str2num(monstart),str2num(daystart),str2num(hourstart),str2num(minstart),str2num(secstart));
                    t2=datetime(str2num(yearend),str2num(monend),str2num(dayend),str2num(hourend),str2num(minend),str2num(secend));
                    t_middle = t1 + (t2-t1)/2;
                    app.TureCoorDateDatePicker.Value=t_middle;
                    app.EditField.Value=[datestr(t_middle,13),'.000'];
                    fclose(fid);
                    h2=msgbox('真值时间中点识别成功，如想修改时间请自行修改','提示');
                    jframe = get(handle(h2),'javaframe');
                   exepath=[pwd,'\1.icon\成功.png'];
                   icon = javax.swing.ImageIcon(exepath);
                   jframe.setFigureIcon(icon);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RTKUIFigure = uifigure('Visible', 'off');
            app.RTKUIFigure.Name = '星舆事后RTK分析工具';
            app.RTKUIFigure.Icon = fullfile(pathToMLAPP, '1.icon', 'star.png');
            app.Toolbar = uitoolbar(app.RTKUIFigure);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'About StarPostdiff V1.01'; '用以支持RTCM格式事后解算分析软件'; 'By Huichao Liu'; '1376542071@qq.com'; 'https://github.com/userlius'; '2022 (c)All rights reserved'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '1.icon', '卫星 (1).png');
            app.Panel_2 = uipanel(app.RTKUIFigure);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = 'Nav文件路径';
            app.NavEditField = uieditfield(app.Panel_2, 'text');
            app.NavEditField.ValueChangedFcn = createCallbackFcn(app, @NavEditFieldValueChanged, true);
            app.NavDropDown = uidropdown(app.Panel_2);
            app.NavDropDown.Items = {'RTCM', 'RINEX'};
            app.NavDropDown.ValueChangedFcn = createCallbackFcn(app, @NavDropDownValueChanged, true);
            app.NavDropDown.Value = 'RTCM';
            app.NavButtond = uibutton(app.Panel_2, 'push');
            app.NavButtond.ButtonPushedFcn = createCallbackFcn(app, @NavButtondPushed, true);
            app.NavButtond.Icon = fullfile(pathToMLAPP, '1.icon', 'icon-folder-128.png');
            app.NavButtond.Text = '';
            app.RefobsDropDown = uidropdown(app.Panel_2);
            app.RefobsDropDown.Items = {'RTCM', 'RINEX'};
            app.RefobsDropDown.ValueChangedFcn = createCallbackFcn(app, @RefobsDropDownValueChanged, true);
            app.RefobsDropDown.Tooltip = {'Ref,Rov文件格式需保持一致'};
            app.RefobsDropDown.Value = 'RTCM';
            app.RefButtond = uibutton(app.Panel_2, 'push');
            app.RefButtond.ButtonPushedFcn = createCallbackFcn(app, @RefButtondPushed, true);
            app.RefButtond.Icon = fullfile(pathToMLAPP, '1.icon', 'icon-folder-128.png');
            app.RefButtond.Text = '';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = 'Ref文件路径';
            app.RefObsEditField = uieditfield(app.Panel_2, 'text');
            app.RefObsEditField.ValueChangedFcn = createCallbackFcn(app, @RefObsEditFieldValueChanged, true);
            app.RefObsEditField.ValueChangingFcn = createCallbackFcn(app, @RefObsEditFieldValueChanging, true);
            app.RovobsDropDown = uidropdown(app.Panel_2);
            app.RovobsDropDown.Items = {'RTCM', 'RINEX'};
            app.RovobsDropDown.ValueChangedFcn = createCallbackFcn(app, @RovobsDropDownValueChanged, true);
            app.RovobsDropDown.Value = 'RTCM';
            app.RovButtond = uibutton(app.Panel_2, 'push');
            app.RovButtond.ButtonPushedFcn = createCallbackFcn(app, @RovButtondPushed, true);
            app.RovButtond.Icon = fullfile(pathToMLAPP, '1.icon', 'icon-folder-128.png');
            app.RovButtond.Text = '';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = 'Rov文件路径';
            app.RovObsEditField = uieditfield(app.Panel_2, 'text');
            app.RovObsEditField.ValueChangedFcn = createCallbackFcn(app, @RovObsEditFieldValueChanged, true);
            app.ConfButtond = uibutton(app.Panel_2, 'push');
            app.ConfButtond.ButtonPushedFcn = createCallbackFcn(app, @ConfButtondPushed, true);
            app.ConfButtond.Icon = fullfile(pathToMLAPP, '1.icon', 'Import_B-128.png');
            app.ConfButtond.Text = '';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = 'conf文件路径';
            app.ConfEditField = uieditfield(app.Panel_2, 'text');
            app.ConfEditField.ValueChangedFcn = createCallbackFcn(app, @ConfEditFieldValueChanged, true);
            app.Button_start = uibutton(app.Panel_2, 'push');
            app.Button_start.ButtonPushedFcn = createCallbackFcn(app, @Button_startPushed, true);
            app.Button_start.Icon = fullfile(pathToMLAPP, '1.icon', 'play_button-512.png');
            app.Button_start.Text = '开始';
            app.CoorXEditField_2Label = uilabel(app.Panel_2);
            app.CoorXEditField_2Label.Text = 'Coor X';
            app.CoorXEditField = uieditfield(app.Panel_2, 'text');
            app.CoorXEditField.ValueChangedFcn = createCallbackFcn(app, @CoorXEditFieldValueChanged, true);
            app.CoorXEditField.Enable = 'off';
            app.CoorXLabel = uilabel(app.Panel_2);
            app.CoorXLabel.Text = 'Coor Y';
            app.CoorYEditField = uieditfield(app.Panel_2, 'text');
            app.CoorYEditField.Enable = 'off';
            app.CoorZLabel = uilabel(app.Panel_2);
            app.CoorZLabel.Text = 'Coor Z';
            app.CoorZEditField = uieditfield(app.Panel_2, 'text');
            app.CoorZEditField.Enable = 'off';
            app.exeCheckBox = uicheckbox(app.Panel_2);
            app.exeCheckBox.ValueChangedFcn = createCallbackFcn(app, @exeCheckBoxValueChanged, true);
            app.exeCheckBox.Text = '执行exe';
            app.Lamp = uilamp(app.Panel_2);
            app.Lamp.Enable = 'off';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = 'configexe全路径';
            app.CMDConfEditField = uieditfield(app.Panel_2, 'text');
            app.CMDButton_start = uibutton(app.Panel_2, 'push');
            app.CMDButton_start.ButtonPushedFcn = createCallbackFcn(app, @CMDButton_startPushed, true);
            app.CMDButton_start.Icon = fullfile(pathToMLAPP, '1.icon', 'cmd.png');
            app.CMDButton_start.Text = 'CMD';
            app.refCoorDropDown = uidropdown(app.Panel_2);
            app.refCoorDropDown.Items = {'File Header', 'Input TureCoor', 'Average Single', 'Pos file'};
            app.refCoorDropDown.ValueChangedFcn = createCallbackFcn(app, @refCoorDropDownValueChanged, true);
            app.refCoorDropDown.Tooltip = {'暂未支持Pos File'};
            app.refCoorDropDown.Value = 'File Header';
            app.CoorXYZEditFieldLabel = uilabel(app.Panel_2);
            app.CoorXYZEditFieldLabel.Text = 'Coor XYZ';
            app.CoorXYZEditField = uieditfield(app.Panel_2, 'text');
            app.CoorXYZEditField.ValueChangedFcn = createCallbackFcn(app, @CoorXYZEditFieldValueChanged, true);
            app.CoorXYZEditField.Enable = 'off';
            app.CoorXYZEditField.Tooltip = {'Coor XYZ 与CoorX Y Z两者输入其中之一即可,若都输入则以Coor XYZ为准'};
            app.Panel = uipanel(app.RTKUIFigure);
            app.CoorXEditField_2Label_2 = uilabel(app.Panel);
            app.CoorXEditField_2Label_2.Text = 'TureCoor X';
            app.TureCoorXEditField = uieditfield(app.Panel, 'text');
            app.TureCoorXEditField.ValueChangedFcn = createCallbackFcn(app, @TureCoorXEditFieldValueChanged, true);
            app.TureCoorXEditField.Enable = 'off';
            app.TureCoorYLabel = uilabel(app.Panel);
            app.TureCoorYLabel.Text = 'TureCoor Y';
            app.TureCoorYEditField = uieditfield(app.Panel, 'text');
            app.TureCoorYEditField.Enable = 'off';
            app.TureCoorZLabel = uilabel(app.Panel);
            app.TureCoorZLabel.Text = 'TureCoor Z';
            app.TureCoorZEditField = uieditfield(app.Panel, 'text');
            app.TureCoorZEditField.ValueChangedFcn = createCallbackFcn(app, @TureCoorZEditFieldValueChanged, true);
            app.TureCoorZEditField.Enable = 'off';
            app.TureButton_start = uibutton(app.Panel, 'push');
            app.TureButton_start.ButtonPushedFcn = createCallbackFcn(app, @TureButton_startPushed, true);
            app.TureButton_start.Icon = fullfile(pathToMLAPP, '1.icon', '转换.png');
            app.TureButton_start.Text = '转换';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Enable = 'off';
            app.Label_5.Tooltip = {'用以支持pos接口的真值坐标转换工具'};
            app.Label_5.Text = '真值坐标格式转换模块';
            app.plotTureCoorButton = uibutton(app.Panel, 'push');
            app.plotTureCoorButton.ButtonPushedFcn = createCallbackFcn(app, @plotTureCoorButtonPushed, true);
            app.plotTureCoorButton.Icon = fullfile(pathToMLAPP, '1.icon', '卫星.png');
            app.plotTureCoorButton.Tooltip = {'请确认坐标真值文件的正确性，是否匹配到当前流动站坐标'};
            app.plotTureCoorButton.Text = 'plot TureCoor';
            app.plotRovCoorButton = uibutton(app.Panel, 'push');
            app.plotRovCoorButton.ButtonPushedFcn = createCallbackFcn(app, @plotRovCoorButtonPushed, true);
            app.plotRovCoorButton.Icon = fullfile(pathToMLAPP, '1.icon', '卫星 (2).png');
            app.plotRovCoorButton.Text = 'plot RovCoor';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = 'TureCoor Date';
            app.TureCoorDateDatePicker = uidatepicker(app.Panel);
            app.TureCoorDateDatePicker.DisplayFormat = 'uuuu/MM/dd';
            app.TureCoorDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @TureCoorDateDatePickerValueChanged, true);
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = ' 00:00:00.000';
            app.TureCoorXYZEditFieldLabel = uilabel(app.Panel);
            app.TureCoorXYZEditFieldLabel.Text = 'TureCoor XYZ';
            app.TureCoorXYZEditField = uieditfield(app.Panel, 'text');
            app.TureCoorXYZEditField.ValueChangedFcn = createCallbackFcn(app, @TureCoorXYZEditFieldValueChanged, true);
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'on', 'off'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Tooltip = {'真值坐标输入形势的切换按钮'};
            app.Switch.Value = 'off';
            app.Tureoutpath = uibutton(app.Panel, 'push');
            app.Tureoutpath.ButtonPushedFcn = createCallbackFcn(app, @TureoutpathButtonPushed, true);
            app.Tureoutpath.Icon = fullfile(pathToMLAPP, '1.icon', '文本输出.png');
            app.Tureoutpath.Text = '输出路径';
            app.TureCoorEditField = uieditfield(app.Panel, 'text');
            app.TureCoorEditField.ValueChangedFcn = createCallbackFcn(app, @TureCoorEditFieldValueChanged, true);
            app.RovButtond_2 = uibutton(app.Panel, 'push');
            app.RovButtond_2.ButtonPushedFcn = createCallbackFcn(app, @RovButtond_2Pushed, true);
            app.RovButtond_2.Icon = fullfile(pathToMLAPP, '1.icon', '自动识别.png');
            app.RovButtond_2.Tooltip = {'自动识别pos文件时间，自动识别仅支持rov文件路径中含有-.v3.0_mulfile格式文件，其余情况需手动添加pos文件。识别结果:起止时间中点'};
            app.RovButtond_2.Text = '';
            % Show the figure after all components are created
            app.RTKUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarPostdiff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RTKUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RTKUIFigure)
        end
    end
end